classdef wordGameSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MainPanel              matlab.ui.container.Panel
        ShowSuggestionsButton  matlab.ui.control.Button
        Green                  matlab.ui.control.Button
        KeyboardToggleButton   matlab.ui.control.StateButton
        Restart                matlab.ui.control.Button
        SuggestionList         matlab.ui.control.ListBox
        Gray                   matlab.ui.control.Button
        Yellow                 matlab.ui.control.Button
        KeyboardPanel          matlab.ui.container.Panel
        BackButton             matlab.ui.control.Button
        EnterButton            matlab.ui.control.Button
        MButton                matlab.ui.control.Button
        NButton                matlab.ui.control.Button
        BButton                matlab.ui.control.Button
        VButton                matlab.ui.control.Button
        CButton                matlab.ui.control.Button
        XButton                matlab.ui.control.Button
        ZButton                matlab.ui.control.Button
        LButton                matlab.ui.control.Button
        KButton                matlab.ui.control.Button
        JButton                matlab.ui.control.Button
        HButton                matlab.ui.control.Button
        GButton                matlab.ui.control.Button
        FButton                matlab.ui.control.Button
        DButton                matlab.ui.control.Button
        SButton                matlab.ui.control.Button
        AButton                matlab.ui.control.Button
        PButton                matlab.ui.control.Button
        OButton                matlab.ui.control.Button
        IButton                matlab.ui.control.Button
        UButton                matlab.ui.control.Button
        YButton                matlab.ui.control.Button
        TButton                matlab.ui.control.Button
        RButton                matlab.ui.control.Button
        EButton                matlab.ui.control.Button
        WButton                matlab.ui.control.Button
        QButton                matlab.ui.control.Button
    end

    % Copyright 2022 The MathWorks, Inc.

    properties (Access = private)
        CurrentLetter % Currently selected letter
        CurrentColor % Currently selected color
        AllLetters;
        AllWords;
        CurrentRow;
        CurrentColumn;
        ToExclude;
        ToInclude;
        CorrectPosition;
        BoardHeigh = 260;
        KeyboardHeigh = 120;
    end
    
    methods (Access = private)
        
        function LoadWords(app)
            % Load the words from a mat file dictionary
            load words.mat words
            app.AllWords = words;
        end

        % This is the callback which is called when clicking on a letter
        % button
        function SelectLetter(app,src,event)
            % store the current color, so it can restore when the current
            % letter is unselected
            if ~isempty(app.CurrentLetter)
                app.CurrentLetter.BackgroundColor = app.CurrentColor;
            end

            % highlight the selected letter
            app.HighlighLetter(event.Source);

            % set the focus to the UI figure, so it keep listening for keys
            app.UIFigure.focus();
        end

        function KeyPressEvent(app,src,event)
            app.KeyPress(event.Source.Text);
        end

        function KeyPress(app, key)
            % conver to lower letters
            key = lower(key);

            if isequal(key, 'enter' ) || isequal(key, 'return') 
                % if pressed the return key, then update the list
                app.UpdateSolutions();
            elseif isequal(key, 'backspace') || isequal(key, 'delete') || isequal(key, 'back')
                % handle all delete keys
                app.ProcessValidKey('');
            elseif isempty(key) || (length(key) == 1 && key >= 'a' && key <= 'z')
                % handle every other valid key
                app.ProcessValidKey(key);
            end
        end

        function ProcessValidKey(app, key)
            % set the current letter with the key that has been pressed
            app.AllLetters{app.CurrentRow, app.CurrentColumn}.Text = upper(key);
            row = app.CurrentRow;
            column = app.CurrentColumn;

            if isempty(key)
                % if it was a delete key, move backwords
                app.MoveToPreviousLetter();
            else
                % if it was a valid letters, move to the next
                app.MoveToNextLetter();
            end

            % update the status and colorize the letters based on all
            % previous status
            app.UpdateStatusData();
            app.CopyStatus(row, column);            
        end

        function UpdateSolutions(app)
            app.SuggestionList.Items = {};
            solutions = app.GetSolutions();
            for i=1:size(solutions,1)
                app.SuggestionList.Items =  [app.SuggestionList.Items {solutions(i,:)}];
            end
        end
       
        % compute all available words based on the status of the letters on
        % the board
        function solutions = GetSolutions(app)
            % make sure all letters have the correct color
            app.ResetHighlight();

            % update all information to be used in the algorithm that
            % select the valid words
            app.UpdateStatusData();
            validChar = app.SelectValidChars();
            
            % select all valid words
            solutions = [];
            for i=1:length(app.AllWords)

                % for each character check if the word is compatible
                compatibleWord = true;
                for j=1:5

                    % interrupt the loop if the word is not compatible
                    if (compatibleWord == false) 
                        continue;
                    end

                    % check compatibility with the known letters in the
                    % right or wrong position
                    if app.CorrectPosition(j) == '-'
                        
                        % if the character is invalid, then mark the word
                        % as incompatible
                        foundInvalidChar = find(validChar == app.AllWords(i,j), 1);
                        if isempty(foundInvalidChar)
                            compatibleWord = false;
                        end

                        % if the character is valid, but it is in an
                        % invalid position, then mark the word as
                        % incompatible
                        if app.IncompatiblePosition(app.AllWords(i,j), j)
                            compatibleWord = false;
                            continue;
                        end
                    elseif app.CorrectPosition(j) ~= app.AllWords(i,j)
                        % check if the caracter in compatible with the
                        % known characters in correct positions
                        compatibleWord = false;
                    end
                end

                % skip the next step if the word was identified as
                % incompatible
                if ~compatibleWord
                    continue;
                end

                % make sure that the word contains all the charactes that
                % are known to be included - this narrow the number of
                % possible solutions and allow to converge quicker
                for j=1:length(app.ToInclude)
                    foundValidChar = find(app.AllWords(i,:) == app.ToInclude(j), 1);
                    if isempty(foundValidChar)
                        compatibleWord = false;
                    end
                end

                % add compatible words to the solution array
                if (compatibleWord)
                    solutions = [solutions; app.AllWords(i,:)];
                end
            end
        end

        % remove the invalid characters from the list, to use when finind
        % the solution
        function validChar = SelectValidChars(app)
            allChar = 'QWERTYUIOPASDFGHJKLZXCVBNM';
            validChar = [];
            for i=1:length(allChar)
                foundLetter = find(app.ToExclude == allChar(i));
                if isempty(foundLetter)
                    validChar = [validChar allChar(i)];
                end
            end
        end

        % update all data, based on the current status of all letters on
        % the board
        function UpdateStatusData(app)
            % initialize the data
            app.ToInclude = '';
            app.ToExclude = '';
            app.CorrectPosition = '-----';

            % look at the color of each letter of the board and update the
            % information of what letter need to be excluded, included or
            % it is in the correct porition
            for i=1:length(app.AllLetters)
                for j=1:5
                    currentletter = app.AllLetters{i,j};

                    % skip empty cells
                    if isempty(currentletter.Text) 
                        continue;
                    end

                    % get the current color of the cell
                    bkcolor = app.AllLetters{i,j}.BackgroundColor;

                    if (bkcolor == app.Gray.BackgroundColor)
                        % if the color is gray, exclude the letter form the
                        % list of valid letters
                        app.ToExclude = [app.ToExclude currentletter.Text];
                    elseif (bkcolor == app.Yellow.BackgroundColor)
                        % if the color is yellow, add the letters to the
                        % list of letters to include
                        app.ToInclude = [app.ToInclude currentletter.Text];
                    else
                        % the letter is green, so add it to the list of
                        % letters in correct position
                        [~, column] = app.GetLetterPosition(currentletter);
                        app.CorrectPosition(column) = currentletter.Text;
                    end
                end
            end        
        end

        % given a character in a specific position, check if this caracter
        % is compatible with all the known words that are in the incorrect
        % position, but looking at all the letters on the board
        function incompatible = IncompatiblePosition(app, letter, column)
            for i=1:length(app.AllLetters)

                % skip empty letters on the board
                if isempty(app.AllLetters{i, column}.Text)
                    continue;
                end

                % check if the letter has been identified as incompatible
                % with the provided position
                letterMatch = app.AllLetters{i, column}.Text == letter;
                isYellow = isequal(app.AllLetters{i, column}.BackgroundColor, app.Yellow.BackgroundColor);
                if letterMatch && isYellow
                    incompatible = true;
                    return;
                end
            end
            incompatible = false;
        end

        % copy the status of the letters in the current row by propagating
        % the known status information
        function CopyStatus(app, row, column)
            letter = app.AllLetters{row, column};

            % if this is an empty letter make it gray
            if isempty(letter.Text)
                letter.BackgroundColor = app.Gray.BackgroundColor;
                letter.Tooltip = '';
                return;
            end

            if (app.CorrectPosition(column) == letter.Text)
                % if the letter is in the correct position, mark it green
                letter.BackgroundColor = app.Green.BackgroundColor;
                letter.Tooltip = app.Green.Tooltip;
            elseif ~isempty(find(app.ToInclude == letter.Text, 1))
                % if the letter is corrent, but not in the right position,
                % mark it yellow
                letter.BackgroundColor = app.Yellow.BackgroundColor;
                letter.Tooltip = app.Yellow.Tooltip;
            end
        end
    
        % given a letter button, return its row and column position
        function [row, column] = GetLetterPosition(app, letter)
            for i=1:length(app.AllLetters)
                for j=1:5
                    if (app.AllLetters{i,j} == letter)
                        row = i;
                        column = j;
                        return;
                    end
                end
            end
        end

        % find the first empty row to insert the new suggestion
        function row = GetEmptyRow(app)
            for i=1:length(app.AllLetters)
                for j=1:5
                    if isempty(app.AllLetters{i,j}.Text)
                        row = i;
                        return;
                    end
                end
            end
        end

        % highlight the given letter, and restore the state of the previous
        % highlighted one
        function HighlighLetter(app, letter)
            % restore the color
            app.ResetHighlight();

            % update the current value
            app.CurrentColor = letter.BackgroundColor;
            app.CurrentLetter = letter;
            app.CurrentLetter.BackgroundColor = [1 1 1];
            app.CurrentLetter.FontColor = [0 0 0];

            [app.CurrentRow, app.CurrentColumn] = app.GetLetterPosition(letter);
        end

        % reset the highligh of the current letter
        function ResetHighlight(app)
            app.CurrentLetter.BackgroundColor = app.CurrentColor;
            app.CurrentLetter.FontColor = [1 1 1];
        end
        
        % move the highligh to the previous letter in the board
        function MoveToPreviousLetter(app)
            % move the cuttent index to the previous letter
            if (app.CurrentColumn > 1)
                app.CurrentColumn = app.CurrentColumn - 1;
            elseif (app.CurrentRow > 1)
                app.CurrentColumn = 5;
                app.CurrentRow = app.CurrentRow - 1;
            end
            % highlight the current letter
            app.HighlighLetter(app.AllLetters{app.CurrentRow, app.CurrentColumn});
        end

        % move the highligh to the next letter in the board
        function MoveToNextLetter(app)
            % move the cuttent index to the next letter
            if (app.CurrentColumn < 5)
                app.CurrentColumn = app.CurrentColumn + 1;
            elseif (app.CurrentRow  < 6)
                app.CurrentColumn = 1;
                app.CurrentRow = app.CurrentRow + 1;
            end
            % highlight the current letter
            app.HighlighLetter(app.AllLetters{app.CurrentRow, app.CurrentColumn});
        end


        function ShowProgress(app)
            d = uiprogressdlg(app.UIFigure,'Title','Computing SVD',...
                'Indeterminate','on');
            drawnow
            
            % Do the SVD computation
            svd(rand(5000));
        
            % close the dialog box
            close(d)
        end

        function Initialize(app)

            % initialize private properties
            app.CorrectPosition = '-----';
            app.CurrentRow = 1;
            app.CurrentColumn = 1;
            app.CurrentColor = app.Gray.BackgroundColor;
            app.CurrentLetter = app.AllLetters{1,1};

            % load all words
            app.LoadWords;
            app.UpdateSolutions();

            % focus the window, so I can start typing
            app.UIFigure.focus();
        end

        function ShowKeyboard(app)
            app.UIFigure.Position(2) = app.UIFigure.Position(2) - app.KeyboardHeigh;
            app.UIFigure.Position(4) = app.BoardHeigh + app.KeyboardHeigh;
            app.KeyboardPanel.Visible = "on";
            app.MainPanel.Position(2) = app.KeyboardHeigh;
            app.MainPanel.Position(4) = app.BoardHeigh;
            app.KeyboardPanel.Position(2) = 0;
        end

        function HideKeyboard(app)
            app.UIFigure.Position(2) = app.UIFigure.Position(2) + app.KeyboardHeigh;
            app.KeyboardPanel.Visible = "off";
            app.UIFigure.Position(4) = app.BoardHeigh;
            app.MainPanel.Position(2) = 0;
            app.MainPanel.Position(4) = app.BoardHeigh;
            app.KeyboardPanel.Position(2) = 0;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % position the app roughly in the center of the screen
            screensize = get( groot, 'Screensize' );
            app.UIFigure.Position(1) = screensize(3)/2 - 420;
            app.UIFigure.Position(2) = screensize(4)/2 - app.BoardHeigh;
            winH = app.BoardHeigh;
            % adding all the button for the letters
            app.AllLetters = {};
            for i=1:6
                for j=1:5
                    b = uibutton(app.MainPanel);
                    b.Text = "";
                    b.BackgroundColor = app.Gray.BackgroundColor;
                    b.FontColor = [1 1 1];
                    b.FontWeight = 'bold';
                    b.ButtonPushedFcn = @app.SelectLetter;
                    b.Position = [10 + 30*j winH - 20 - 30*i 25 25];
                    app.AllLetters{i,j} = b;
                end
            end
            %app.HideKeyboard();
            for i=1:length(app.KeyboardPanel.Children)
                app.KeyboardPanel.Children(i).ButtonPushedFcn =  @app.KeyPressEvent;
            end
            app.HideKeyboard();
            app.Initialize();
        end

        % Callback function
        function LetterincorrectpositionMenuSelected(app, event)

        end

        % Value changed function: KeyboardToggleButton
        function ToggleKeyboardToggleButton(app, event)
            % toggle the visibility of the keyboard
            value = app.KeyboardToggleButton.Value;
            if (value)
                app.ShowKeyboard();
            else
                app.HideKeyboard();
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            app.KeyPress(event.Key);
        end

        % Button pushed function: Gray, Green, Yellow
        function StatusButtonPushed(app, event)
            % set the status for the current letter
            app.CurrentLetter.BackgroundColor = event.Source.BackgroundColor;
            app.CurrentColor = app.CurrentLetter.BackgroundColor;
            app.CurrentLetter.Tooltip = event.Source.Tooltip;
            % move the current to the next letter - this allows to quickly
            % select the status for a set of letters consecutively
            app.MoveToNextLetter();
        end

        % Button pushed function: Restart
        function RestartButton(app, event)
            % show a progress dialog
            d = uiprogressdlg(app.UIFigure,'Title','Restarting',...
                'Indeterminate','on');
            drawnow
                    
            % re-initialize the board
            for i=1:length(app.AllLetters)
                for j=1:5
                    app.AllLetters{i,j}.Text = "";
                    app.AllLetters{i,j}.BackgroundColor = app.Gray.BackgroundColor;
                end
            end
            app.Initialize();

            % close the progress dialog
            close(d)
        end

        % Button pushed function: ShowSuggestionsButton
        function ShowSuggestionsButtonPushed(app, event)
            app.UpdateSolutions();
        end

        % Value changed function: SuggestionList
        function SuggestionListValueChanged(app, event)
            % get the index to the first empty row
            emptyRow = app.GetEmptyRow();
            selectedWord = app.SuggestionList.Value;
            % update the data on all letters currectly on the board
            app.UpdateStatusData();
            % add the selected word and copy the state from the previous
            % status for each letters (yellow letters will be yellow and
            % green letter will be green if in the same position)
            for i=1:5
                app.AllLetters{emptyRow,i}.Text = selectedWord(i);
                app.CopyStatus(emptyRow, i);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Word Game Solver';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.KeyboardPanel = uipanel(app.UIFigure);
            app.QButton = uibutton(app.KeyboardPanel, 'push');
            app.QButton.Text = 'Q';
            app.WButton = uibutton(app.KeyboardPanel, 'push');
            app.WButton.Text = 'W';
            app.EButton = uibutton(app.KeyboardPanel, 'push');
            app.EButton.Text = 'E';
            app.RButton = uibutton(app.KeyboardPanel, 'push');
            app.RButton.Text = 'R';
            app.TButton = uibutton(app.KeyboardPanel, 'push');
            app.TButton.Text = 'T';
            app.YButton = uibutton(app.KeyboardPanel, 'push');
            app.YButton.Text = 'Y';
            app.UButton = uibutton(app.KeyboardPanel, 'push');
            app.UButton.Text = 'U';
            app.IButton = uibutton(app.KeyboardPanel, 'push');
            app.IButton.Text = 'I';
            app.OButton = uibutton(app.KeyboardPanel, 'push');
            app.OButton.Text = 'O';
            app.PButton = uibutton(app.KeyboardPanel, 'push');
            app.PButton.Text = 'P';
            app.AButton = uibutton(app.KeyboardPanel, 'push');
            app.AButton.Text = 'A';
            app.SButton = uibutton(app.KeyboardPanel, 'push');
            app.SButton.Text = 'S';
            app.DButton = uibutton(app.KeyboardPanel, 'push');
            app.DButton.Text = 'D';
            app.FButton = uibutton(app.KeyboardPanel, 'push');
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.KeyboardPanel, 'push');
            app.GButton.Text = 'G';
            app.HButton = uibutton(app.KeyboardPanel, 'push');
            app.HButton.Text = 'H';
            app.JButton = uibutton(app.KeyboardPanel, 'push');
            app.JButton.Text = 'J';
            app.KButton = uibutton(app.KeyboardPanel, 'push');
            app.KButton.Text = 'K';
            app.LButton = uibutton(app.KeyboardPanel, 'push');
            app.LButton.Text = 'L';
            app.ZButton = uibutton(app.KeyboardPanel, 'push');
            app.ZButton.Text = 'Z';
            app.XButton = uibutton(app.KeyboardPanel, 'push');
            app.XButton.Text = 'X';
            app.CButton = uibutton(app.KeyboardPanel, 'push');
            app.CButton.Text = 'C';
            app.VButton = uibutton(app.KeyboardPanel, 'push');
            app.VButton.Text = 'V';
            app.BButton = uibutton(app.KeyboardPanel, 'push');
            app.BButton.Text = 'B';
            app.NButton = uibutton(app.KeyboardPanel, 'push');
            app.NButton.Text = 'N';
            app.MButton = uibutton(app.KeyboardPanel, 'push');
            app.MButton.Text = 'M';
            app.EnterButton = uibutton(app.KeyboardPanel, 'push');
            app.EnterButton.Text = 'Enter';
            app.BackButton = uibutton(app.KeyboardPanel, 'push');
            app.BackButton.Text = 'Back';
            app.MainPanel = uipanel(app.UIFigure);
            app.Yellow = uibutton(app.MainPanel, 'push');
            app.Yellow.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.Yellow.Tooltip = {'The letter is in the word but in the wrong spot.'};
            app.Yellow.Text = '';
            app.Gray = uibutton(app.MainPanel, 'push');
            app.Gray.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.Gray.Tooltip = {'The letter is not in the word in any spot.'};
            app.Gray.Text = '';
            app.SuggestionList = uilistbox(app.MainPanel);
            app.SuggestionList.Items = {};
            app.SuggestionList.ValueChangedFcn = createCallbackFcn(app, @SuggestionListValueChanged, true);
            app.SuggestionList.Value = {};
            app.Restart = uibutton(app.MainPanel, 'push');
            app.Restart.ButtonPushedFcn = createCallbackFcn(app, @RestartButton, true);
            app.Restart.Icon = fullfile(pathToMLAPP, 'revert.svg');
            app.Restart.Text = '';
            app.KeyboardToggleButton = uibutton(app.MainPanel, 'state');
            app.KeyboardToggleButton.ValueChangedFcn = createCallbackFcn(app, @ToggleKeyboardToggleButton, true);
            app.KeyboardToggleButton.Icon = fullfile(pathToMLAPP, 'keyboard.svg');
            app.KeyboardToggleButton.Text = '';
            app.Green = uibutton(app.MainPanel, 'push');
            app.Green.ButtonPushedFcn = createCallbackFcn(app, @StatusButtonPushed, true);
            app.Green.Tooltip = {'The letter is in the word and in the correct spot.'};
            app.Green.Text = '';
            app.ShowSuggestionsButton = uibutton(app.MainPanel, 'push');
            app.ShowSuggestionsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSuggestionsButtonPushed, true);
            app.ShowSuggestionsButton.Text = 'UPDATE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wordGameSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end